classdef Kuans_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        StimLamp           matlab.ui.control.Lamp
        StimLampLabel      matlab.ui.control.Label
        Button_2           matlab.ui.control.Button
        Button             matlab.ui.control.Button
        ViewingFrameLabel  matlab.ui.control.Label
        LoadButton         matlab.ui.control.Button
        Slider             matlab.ui.control.Slider
        PauseButton        matlab.ui.control.Button
        PlayButton         matlab.ui.control.Button
        Image_2            matlab.ui.control.Image
        Image              matlab.ui.control.Image
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % LOCAL VARIABLES
        file_name = NaN;
        file_path_img = NaN;
        ca_data = NaN;
        exp_data = NaN;
        num_frames = NaN;
        stimTimes = NaN;
        stimOnRast = NaN;
        somaticF = NaN;
        red = NaN;
        green = NaN;
        tOut = NaN;
        indx = 1;
        playing = 0;
    end
    
    methods (Access = private)
        
        function results = update(app)
            app.Image_2.ImageSource = cat(3,zeros(64,64),mat2gray(app.green(:,:,app.indx)),zeros(64,64));
            app.Image.ImageSource = cat(3,mat2gray(app.red(:,:,app.indx)),zeros(64,64),zeros(64,64));
            app.ViewingFrameLabel.Text = strcat("Viewing Frame: ",num2str(app.indx));
            if app.stimOnRast(app.indx) == 1
                app.StimLamp.Color = [0,1,0];
            else
                app.StimLamp.Color = [1,0,0];
            end
            plot(app.UIAxes,app.somaticF(:,1:5),'k-');
            app.Slider.Value = app.indx;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [Ca_file_name, ~] = uigetfile();
            load(Ca_file_name);
            % files from "Ca_data_for_gui.mat"
            app.stimTimes =  stim_times;
            app.stimOnRast = stim_on_rast;
            app.somaticF = somaticF;
            app.num_frames = size(app.somaticF,2);
            app.red = red;
            app.green = green;
            app.tOut = t_out;
            app.Image_2.ImageSource = cat(3,zeros(64,64),mat2gray(app.green(:,:,app.indx)),zeros(64,64));
            app.Image.ImageSource = cat(3,mat2gray(app.red(:,:,app.indx)),zeros(64,64),zeros(64,64));
            app.Slider.Limits = [1, app.num_frames];
            app.ViewingFrameLabel.Text = "Viewing Frame: 1";
            
        end

        % Callback function
        function LoadExpFileButtonPushed(app, event)
            [app.exp_file_name, app.exp_file_path] = uigetfile();
            app.exp_data = load(app.exp_file_name);
            app.Lamp2.Color = [0 1 0];
        end

        % Callback function
        function LoadImageStackButtonPushed(app, event)
            [app.imStack_file_name, app.imStack_file_path] = uigetfile();
            app.imStack = load(app.imStack_file_name);
            app.Lamp3.Color = [0 1 0];
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.indx = round(value);
            disp(num2str(app.indx));
            update(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.indx = app.indx + 1;
            update(app);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.indx = app.indx - 1;
            update(app);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.playing = 1;
            while app.playing == 1
                app.indx = app.indx + 1;
                pause(0.08);
                update(app);
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.playing = 0;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Fluorescence Trace')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Avg. Brightness')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-5 5];
            app.UIAxes.XTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Image = uiimage(app.UIFigure);
            app.Image_2 = uiimage(app.UIFigure);
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1000];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ViewingFrameLabel = uilabel(app.UIFigure);
            app.ViewingFrameLabel.Text = 'Viewing Frame';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = {'-->'; ''};
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = {'<--'; ''};
            app.StimLampLabel = uilabel(app.UIFigure);
            app.StimLampLabel.Text = 'Stim';
            app.StimLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kuans_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end