classdef quick_2p_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        TotalTimesEditField          matlab.ui.control.NumericEditField
        TotalTimesEditFieldLabel     matlab.ui.control.Label
        FrameDursEditField           matlab.ui.control.NumericEditField
        FrameDursEditFieldLabel      matlab.ui.control.Label
        ExpLoadButton                matlab.ui.control.Button
        ExpFileNamesListBox          matlab.ui.control.ListBox
        ExperimentFilesListBoxLabel  matlab.ui.control.Label
        SelectExpFolderButton        matlab.ui.control.Button
        ImgLoadButton                matlab.ui.control.Button
        ImgFileNamesListBox          matlab.ui.control.ListBox
        ImageFilesLabel              matlab.ui.control.Label
        SelectImgFolderButton        matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        IDButton                     matlab.ui.control.Button
        toSpinner                    matlab.ui.control.Spinner
        toSpinnerLabel               matlab.ui.control.Label
        Spinner                      matlab.ui.control.Spinner
        GOButton                     matlab.ui.control.Button
        GuessPVRadiusSpinner         matlab.ui.control.Spinner
        GuessPVRadiusSpinnerLabel    matlab.ui.control.Label
        FindCellsButton              matlab.ui.control.Button
        GuessPVsSpinner              matlab.ui.control.Spinner
        GuessPVsSpinnerLabel         matlab.ui.control.Label
        ShowMaxProjectionButtonRFP   matlab.ui.control.Button
        Frame_Label_gfp              matlab.ui.control.Label
        Button_gfp_DEC               matlab.ui.control.Button
        Button_gfp_dec               matlab.ui.control.Button
        Button_gfp_INC               matlab.ui.control.Button
        Button_gfp_inc               matlab.ui.control.Button
        Frame_Label_rfp              matlab.ui.control.Label
        Button_rfp_DEC               matlab.ui.control.Button
        Button_rfp_dec               matlab.ui.control.Button
        Button_rfp_INC               matlab.ui.control.Button
        Button_rfp_inc               matlab.ui.control.Button
        GFPChannelLabel              matlab.ui.control.Label
        RFPChannelLabel              matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        Image1                       matlab.ui.control.Image
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        %FILE VARIABLES
        file_path_img = ""; % The location of image .tiff files
        file_path_exp = ""; % The location of experiment .mat files
        file_names_img = ""; %names of all experiment .tif files in selected folder
        file_names_exp = ""; %names of all experiment .mat files in selected folder
        file_selected_img = ""; %name of selected file for analysis
        file_selected_exp = ""; %name of selected file for analysis
        file_img_loaded = NaN;
        file_exp_loaded = NaN;
        
        %IMG VARIABLES
        im_info = NaN; %struct to hold image info from loaded file
        num_frames = NaN; %number of frames in loaded data
        x_px = NaN; %number of pixels x-dim
        y_px = NaN; %number of pixels y dim
        gfp_indx = 0; %current gfp frame
        rfp_indx = 0; %current gfp frame
        PV_num_est = 1; % Estimated number of PV Neurons in frame
        radius_est = 5; % Estimated radius of PV neurons in frame
        max_rfp_im = NaN; %holds the max rfp projection image
        max_gfp_im = NaN; %holds the max gfp projection image
        im_centers = NaN; %holds XY center values for identified Cells
        im_radii = NaN; %holds radius values for identified Cells
        two_arrow_step = 20; %number of frames to quick step [>>] & [<<]
        masks = NaN; %to store the masks of identified neurons
        gfp_stack = NaN; %stores all images from that filter
        rfp_stack = NaN; %stores all images from that filter
        num_cells = NaN; %number of identified and tracked neurons
        max_rfp_start = 1; % lower bound for max projection stack creation
        max_rfp_end = 2; % top bound for max projection stack creation
        
        %EXP VARIABLES
        exp_file_loaded = NaN;
        exp_file = NaN; %handle for storing experiment file cell array.
        %exp file columns: 1=Duration (ms), 2=Frequency (Hz), 3=Amplitude
        %(V), 4=Stim_ON_Times, 5=First_Frame_Capture_Time (from 2P)
        frame_dur = NaN; %duration of each frame in the overall scan
        frame_times = NaN; %time of each frame in the scan  (zero if first frame time)
        scan_dur = NaN; %overall scan duration (all frames)
        stim_times = NaN; %time of each stim presentation (zero is first frame time)
        num_trials = NaN;
        raw_ca_data = NaN;
        raw_ca_std = NaN;
        stim_freqs = NaN;
        inter_stim_interval = NaN;
        
        %OUTPUT VARIABLES
        t_out = table();
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImgFolderButton
        function SelectImgFolderButtonPushed(app, event)
            app.file_path_img = uigetdir();
            cd(app.file_path_img);
            file_names = struct2cell(dir('*.tif'));
            for n=1:size(file_names,2)
                app.file_names_img(n) = file_names{1,n};
            end
            app.ImgFileNamesListBox.Items = app.file_names_img;
            app.SelectImgFolderButton.BackgroundColor = [0.39 0.83 0.07];
        end

        % Value changed function: ImgFileNamesListBox
        function ImgFileNamesListBoxValueChanged(app, event)
            app.file_selected_img = app.ImgFileNamesListBox.Value;
            app.ImgLoadButton.FontColor = [0 0 0];
        end

        % Button pushed function: ImgLoadButton
        function ImgLoadButtonPushed(app, event)
            
            app.ImgLoadButton.BackgroundColor = [0.93 0.69 0.13]; %button yellow
            app.im_info = imfinfo(app.file_selected_img);
            app.num_frames = length(app.im_info)/2;
            app.x_px = app.im_info(1).Width;
            app.y_px = app.im_info(1).Height;
            app.Image1.ImageSource = cat(3,mat2gray(imread(app.file_selected_img,app.rfp_indx*2+2)),zeros(app.x_px,app.y_px),zeros(app.x_px,app.y_px));
            app.Image2.ImageSource = cat(3,zeros(app.x_px,app.y_px),mat2gray(imread(app.file_selected_img,app.gfp_indx*2+1)),zeros(app.x_px,app.y_px));
            app.gfp_stack = zeros(app.x_px,app.y_px,app.num_frames);
            app.rfp_stack = zeros(app.x_px,app.y_px,app.num_frames); 
            for n=1:app.num_frames
                app.gfp_stack(:,:,n) = mat2gray(imread(app.file_selected_img,2*n-1));
                app.rfp_stack(:,:,n) = mat2gray(imread(app.file_selected_img,2*n));
            end
            app.ImgLoadButton.BackgroundColor = [0.39 0.83 0.07]; %button green
            app.file_img_loaded = 1;
        end

        % Button pushed function: Button_rfp_inc
        function Button_rfp_incPushed(app, event)
            if app.rfp_indx == app.num_frames-1
                return %at upper frame limit
            end
            app.rfp_indx = app.rfp_indx + 1;
            app.Image1.ImageSource = cat(3,app.rfp_stack(:,:,app.rfp_indx),zeros(app.x_px,app.y_px),zeros(app.x_px,app.y_px));
            app.Frame_Label_rfp.Text = strcat("Frame # ",num2str(app.rfp_indx+1));
            app.ShowMaxProjectionButtonRFP.BackgroundColor = [0.96 0.96 0.96];
        end

        % Button pushed function: Button_rfp_dec
        function Button_rfp_decPushed(app, event)
            if app.rfp_indx == 0
                return %at lower frame limit
            end
            app.rfp_indx = app.rfp_indx - 1;
            app.Image1.ImageSource = cat(3,app.rfp_stack(:,:,app.rfp_indx),zeros(app.x_px,app.y_px),zeros(app.x_px,app.y_px));
            app.Frame_Label_rfp.Text = strcat("Frame # ",num2str(app.rfp_indx+1));
            app.ShowMaxProjectionButtonRFP.BackgroundColor = [0.96 0.96 0.96];
        end

        % Button pushed function: Button_gfp_inc
        function Button_gfp_incPushed(app, event)
            if app.gfp_indx == app.num_frames-1
                return %at upper frame limit
            end
            app.gfp_indx = app.gfp_indx + 1;
            app.Image2.ImageSource = cat(3,zeros(app.x_px,app.y_px),app.gfp_stack(:,:,app.gfp_indx),zeros(app.x_px,app.y_px));
            app.Frame_Label_gfp.Text = strcat("Frame # ",num2str(app.gfp_indx+1));
        end

        % Button pushed function: Button_gfp_dec
        function Button_gfp_decPushed(app, event)
            if app.gfp_indx == 0
                return %at lower frame limit
            end
            app.gfp_indx = app.gfp_indx - 1;
            app.Image2.ImageSource = cat(3,zeros(app.x_px,app.y_px),app.gfp_stack(:,:,app.gfp_indx),zeros(app.x_px,app.y_px));
            app.Frame_Label_gfp.Text = strcat("Frame # ",num2str(app.gfp_indx+1));
        end

        % Button pushed function: ShowMaxProjectionButtonRFP
        function ShowMaxProjectionButtonRFPPushed(app, event)
            %calculate the max pixel brigtness from first 50 frames
            temp = app.rfp_stack(:,:,app.max_rfp_start:app.max_rfp_end);
            temp = max(temp,[],3); %calculate X by Y max values for frames 1-50
            app.Image1.ImageSource = cat(3,temp,zeros(app.x_px,app.y_px),zeros(app.x_px,app.y_px));
            app.max_rfp_im = cat(3,temp,zeros(app.x_px,app.y_px),zeros(app.x_px,app.y_px));
            app.ShowMaxProjectionButtonRFP.BackgroundColor = [0.39 0.83 0.07];
            app.FindCellsButton.FontColor = [0 0 0];
            
        end

        % Value changed function: GuessPVsSpinner
        function GuessPVsSpinnerValueChanged(app, event)
            app.PV_num_est = app.GuessPVsSpinner.Value;
        end

        % Button pushed function: FindCellsButton
        function FindCellsButtonPushed(app, event)
            sensitivity = 0.86;
            t_start = GetSecs;
            while true 
                [centers,radii] = imfindcircles(app.max_rfp_im,5,'ObjectPolarity','bright','Sensitivity',sensitivity);
                warning('off','last');
                if isempty(centers)
                    sensitivity = sensitivity + 0.001;
                elseif size(centers,1) < app.PV_num_est
                    sensitivity = sensitivity + 0.001;
                elseif size(centers,1) > app.PV_num_est
                    sensitivity = sensitivity - 0.001;
                elseif size(centers,1) == app.PV_num_est
                    break
                end
                pause(0);
                if GetSecs - t_start > 10
                    break
                end
            end
            imshow(app.max_rfp_im); hold on;
            viscircles(centers,radii,'Color','k','EnhanceVisibility',false);
            hold off;
            app.im_centers = centers;
            app.im_radii = radii;
            app.FindCellsButton.BackgroundColor = [0.93 0.69 0.13]; %button yellow
            app.GOButton.FontColor = [0.5 0.5 0.5];
        end

        % Value changed function: GuessPVRadiusSpinner
        function GuessPVRadiusSpinnerValueChanged(app, event)
            app.radius_est = app.GuessPVRadiusSpinner.Value;
        end

        % Callback function
        function Image1Clicked(app, event)
            
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            app.FindCellsButton.BackgroundColor = [0.39 0.83 0.07]; %button green
            app.GOButton.BackgroundColor = [0.93 0.69 0.13]; %button yellow
            disp('Processing Data...');
            
            %local calculations, variable assignments
            real_frame_time = app.scan_dur / app.num_frames;
            app.frame_times = [0:real_frame_time:(app.scan_dur-real_frame_time)];
            app.stim_times = app.exp_file{4} - app.exp_file{5}(end);
            app.stim_freqs = app.exp_file{2};
            app.raw_ca_data = zeros(length(app.im_radii),app.num_frames);
            app.raw_ca_std = zeros(length(app.im_radii),app.num_frames);
            app.num_trials = length(app.exp_file{1});
            app.inter_stim_interval = mean(diff(app.stim_times));
            app.num_cells = length(app.im_radii);
            
            %assigning variables to output table
            app.t_out.trial(1:app.num_trials) = [1:app.num_trials];
            app.t_out.stim_duration(1:app.num_trials) = app.exp_file{1};
            app.t_out.stim_freq(1:app.num_trials) = app.stim_freqs;
            app.t_out.stim_amps(1:app.num_trials) = app.exp_file{3};
            app.t_out.stim_ON_time(1:app.num_trials) = app.stim_times;
            
            %Create Image Masks for subtracting out pixels representing one neuron
            app.masks = zeros(app.x_px,app.y_px, length(app.im_radii));
            for n=1:app.num_cells
                [columnsInImage, rowsInImage] = meshgrid(1:app.x_px, 1:app.y_px);
                app.masks(:,:,n) = (rowsInImage - app.im_centers(n,2)).^2 + (columnsInImage - app.im_centers(n,1)).^2 <= app.im_radii(n).^2;
                for f=1:app.num_frames
                    masked_im = squeeze(app.gfp_stack(:,:,f)) .* app.masks(:,:,n);
                    masked_im(masked_im == 0) = []; %clear away zeros
                    app.raw_ca_data(n,f) = mean(masked_im);
                    app.raw_ca_std(n,f) = std(masked_im);
                end
            end
            
            %Parse through trials
            num_pre_stim_frames = 0;
            for n=1:app.num_trials
                if app.stim_times(n) > app.frame_times(end)
                    %this stim occured after scan finished. skip.
                    break
                else
                    if n < app.num_trials
                        stop_epoch = app.stim_times(n+1);
                        start_epoch = app.stim_times(n-num_pre_stim_frames);
                        %find closest frames in scan
                        [~,start_frame]=min(abs(app.frame_times - start_epoch));
                        [~,stop_frame]=min(abs(app.frame_times - stop_epoch));
                        %extract calcium signal during that time
                        app.t_out.signal{n} = app.raw_ca_data(start_frame:stop_frame);
                    else
                        %Final trial, how much scan remains?
                        remaining_time = app.frame_times(end) - app.stim_times(n);
                        if remaining_time > app.inter_stim_interval
                            %more than enough time remains on the scan
                            start_epoch = app.stim_times(n);
                            stop_epoch = app.stim_times(n) + app.inter_stim_interval;
                            %find closest frames in scan
                            [~,start_frame]=min(abs(app.frame_times - start_epoch));
                            [~,stop_frame]=min(abs(app.frame_times - stop_epoch));
                            %extract calcium signal during that time
                            app.t_out.signal{n} = app.raw_ca_data(start_frame:stop_frame);
                        else
                            %less than one ITI remains on scan, go to end
                            start_epoch = app.stim_times(n);
                            [~,start_frame]=min(abs(app.frame_times - start_epoch));
                            app.t_out.signal{n} = app.raw_ca_data(start_frame:end);
                        end
                    end
                end
            end
            
            %make PSTH
            lengths = zeros(1,app.num_trials);
            for n=1:app.num_trials
                lengths(n) = length(app.t_out.signal{n});
            end
            lengths(lengths==0) = [];
            new_length = min(lengths);
            if new_length>(2*std(lengths)+mean(lengths)) || new_length<(mean(lengths)-2*std(lengths))
                %the smallest langth trial is an outlier, we should remove
                %it and recalculate the minimum length
            end
            trimmed_sig = zeros(app.num_trials,new_length);
            for n=1:app.num_trials
                if isempty(app.t_out.signal{n})
                    break
                end
                trimmed_sig(n,:) = app.t_out.signal{n}(1:new_length);
            end
            errorbar(app.UIAxes,[0:0.1:size(trimmed_sig,2)/(1/app.frame_dur)-0.1],mean(trimmed_sig,1),std(trimmed_sig,1));
            title(app.UIAxes,'PSTH');
            xlabel(app.UIAxes,'Time post-stim (sec)');
            ylabel(app.UIAxes,'dF/F');
            
            t_out = app.t_out;
            save("TEST_OUTPUT_01.mat",'trimmed_sig','t_out');
            %...
        end

        % Button pushed function: SelectExpFolderButton
        function SelectExpFolderButtonPushed(app, event)
            %old_dir = pwd;
            app.file_path_exp = uigetdir();
            cd(app.file_path_exp);
            file_names = struct2cell(dir('*.mat'));
            for n=1:size(file_names,2)
                app.file_names_exp(n) = file_names{1,n};
            end
            app.ExpFileNamesListBox.Items = app.file_names_exp;
            app.SelectImgFolderButton.BackgroundColor = [0.39 0.83 0.07];
            %cd(old_dir);
        end

        % Value changed function: ExpFileNamesListBox
        function ExpFileNamesListBoxValueChanged(app, event)
            app.file_selected_exp = app.ExpFileNamesListBox.Value;
            app.ExpLoadButton.FontColor = [0 0 0];
        end

        % Button pushed function: ExpLoadButton
        function ExpLoadButtonPushed(app, event)
            disp("Loading Experiment File...");
            app.file_exp_loaded = 1;
            app.exp_file = load(app.file_selected_exp); %loads in .mat struct chosen above
            app.exp_file = app.exp_file.Data; %extracts cell array form inside struct
            app.GOButton.FontColor = [0 0 0];
            app.GOButton.BackgroundColor = [0.39 0.83 0.07]; %button green
        end

        % Image clicked function: Image1
        function Image1Clicked2(app, event)
            
        end

        % Button pushed function: Button_rfp_INC
        function Button_rfp_INCPushed(app, event)
            %large increase in frames
            if app.rfp_indx == app.num_frames-1
                return %at upper frame limit
            end
            app.rfp_indx = app.rfp_indx + app.two_arrow_step;
            app.Image1.ImageSource = cat(3,mat2gray(imread(app.file_selected_img,app.rfp_indx*2+2)),zeros(app.x_px,app.y_px),zeros(app.x_px,app.y_px));
            app.Frame_Label_rfp.Text = strcat("Frame # ",num2str(app.rfp_indx+1));
            app.ShowMaxProjectionButtonRFP.BackgroundColor = [0.96 0.96 0.96];
        end

        % Button pushed function: Button_rfp_DEC
        function Button_rfp_DECPushed(app, event)
            %large dec
        end

        % Value changed function: FrameDursEditField
        function FrameDursEditFieldValueChanged(app, event)
            app.frame_dur = app.FrameDursEditField.Value;
        end

        % Value changed function: TotalTimesEditField
        function TotalTimesEditFieldValueChanged(app, event)
            app.scan_dur = app.TotalTimesEditField.Value;
        end

        % Callback function
        function ShowMaxProjectionButtonGFPPushed(app, event)
            %same as the RFP version but for GFP.
            %calculate the max pixel brigtness from first 50 frames
            temp = app.rfp_stack(:,:,1:50);
            temp = max(temp,[],3); %calculate X by Y max values for frames 1-50
            app.Image2.ImageSource = cat(3,zeros(app.x_px,app.y_px),temp,zeros(app.x_px,app.y_px));
            app.max_gfp_im = cat(3,temp,zeros(app.x_px,app.y_px),zeros(app.x_px,app.y_px));
            app.ShowMaxProjectionButtonGFP.BackgroundColor = [0.39 0.83 0.07];
            app.FindCellsButton.FontColor = [0 0 0];
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.max_rfp_start = app.Spinner.Value;
        end

        % Value changed function: toSpinner
        function toSpinnerValueChanged(app, event)
            app.max_rfp_end = app.toSpinner.Value;
        end

        % Button pushed function: IDButton
        function IDButtonPushed(app, event)
            %ID neurons
            f1 = figure; hold on;
            imshow(app.gfp_stack(:,:,app.gfp_indx));
            set(f1,'Position',[100,100,700,700]);
            [x,y] = ginput(2);
            radius = sqrt((x(2)-x(1))^2+(y(2)-y(1))^2);
            center = x(1)+(x(2)-x(1));
            center(2) = y(1)+(y(2)-y(1));
            viscircles(center,radius,'Color','k','EnhanceVisibility',false);
            disp(strcat('Neuron at (',num2str(x),',',num2str(y),')'));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {596, 596};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {167, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectImgFolderButton = uibutton(app.LeftPanel, 'push');
            app.SelectImgFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImgFolderButtonPushed, true);
            app.SelectImgFolderButton.Text = 'Select Img Folder';
            app.ImageFilesLabel = uilabel(app.LeftPanel);
            app.ImageFilesLabel.Text = 'Image Files';
            app.ImgFileNamesListBox = uilistbox(app.LeftPanel);
            app.ImgFileNamesListBox.ValueChangedFcn = createCallbackFcn(app, @ImgFileNamesListBoxValueChanged, true);
            app.ImgLoadButton = uibutton(app.LeftPanel, 'push');
            app.ImgLoadButton.ButtonPushedFcn = createCallbackFcn(app, @ImgLoadButtonPushed, true);
            app.ImgLoadButton.Text = 'Load';
            app.SelectExpFolderButton = uibutton(app.LeftPanel, 'push');
            app.SelectExpFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExpFolderButtonPushed, true);
            app.SelectExpFolderButton.Text = 'Select Exp Folder';
            app.ExperimentFilesListBoxLabel = uilabel(app.LeftPanel);
            app.ExperimentFilesListBoxLabel.Text = 'Experiment Files';
            app.ExpFileNamesListBox = uilistbox(app.LeftPanel);
            app.ExpFileNamesListBox.ValueChangedFcn = createCallbackFcn(app, @ExpFileNamesListBoxValueChanged, true);
            app.ExpLoadButton = uibutton(app.LeftPanel, 'push');
            app.ExpLoadButton.ButtonPushedFcn = createCallbackFcn(app, @ExpLoadButtonPushed, true);
            app.ExpLoadButton.Text = 'Load';
            app.FrameDursEditFieldLabel = uilabel(app.LeftPanel);
            app.FrameDursEditFieldLabel.Text = 'Frame Dur (s)';
            app.FrameDursEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FrameDursEditField.ValueChangedFcn = createCallbackFcn(app, @FrameDursEditFieldValueChanged, true);
            app.TotalTimesEditFieldLabel = uilabel(app.LeftPanel);
            app.TotalTimesEditFieldLabel.Text = 'Total Time (s)';
            app.TotalTimesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TotalTimesEditField.ValueChangedFcn = createCallbackFcn(app, @TotalTimesEditFieldValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image1 = uiimage(app.RightPanel);
            app.Image1.ImageClickedFcn = createCallbackFcn(app, @Image1Clicked2, true);
            app.Image2 = uiimage(app.RightPanel);
            app.RFPChannelLabel = uilabel(app.RightPanel);
            app.RFPChannelLabel.Text = 'RFP Channel';
            app.GFPChannelLabel = uilabel(app.RightPanel);
            app.GFPChannelLabel.Text = 'GFP Channel';
            app.Button_rfp_inc = uibutton(app.RightPanel, 'push');
            app.Button_rfp_inc.ButtonPushedFcn = createCallbackFcn(app, @Button_rfp_incPushed, true);
            app.Button_rfp_inc.Text = '>';
            app.Button_rfp_INC = uibutton(app.RightPanel, 'push');
            app.Button_rfp_INC.ButtonPushedFcn = createCallbackFcn(app, @Button_rfp_INCPushed, true);
            app.Button_rfp_INC.Text = '>>';
            app.Button_rfp_dec = uibutton(app.RightPanel, 'push');
            app.Button_rfp_dec.ButtonPushedFcn = createCallbackFcn(app, @Button_rfp_decPushed, true);
            app.Button_rfp_dec.Text = '<';
            app.Button_rfp_DEC = uibutton(app.RightPanel, 'push');
            app.Button_rfp_DEC.ButtonPushedFcn = createCallbackFcn(app, @Button_rfp_DECPushed, true);
            app.Button_rfp_DEC.Text = '<<';
            app.Frame_Label_rfp = uilabel(app.RightPanel);
            app.Frame_Label_rfp.Text = 'Frame # 1';
            app.Button_gfp_inc = uibutton(app.RightPanel, 'push');
            app.Button_gfp_inc.ButtonPushedFcn = createCallbackFcn(app, @Button_gfp_incPushed, true);
            app.Button_gfp_inc.Text = '>';
            app.Button_gfp_INC = uibutton(app.RightPanel, 'push');
            app.Button_gfp_INC.Text = '>>';
            app.Button_gfp_dec = uibutton(app.RightPanel, 'push');
            app.Button_gfp_dec.ButtonPushedFcn = createCallbackFcn(app, @Button_gfp_decPushed, true);
            app.Button_gfp_dec.Text = '<';
            app.Button_gfp_DEC = uibutton(app.RightPanel, 'push');
            app.Button_gfp_DEC.Text = '<<';
            app.Frame_Label_gfp = uilabel(app.RightPanel);
            app.Frame_Label_gfp.Text = 'Frame # 1';
            app.ShowMaxProjectionButtonRFP = uibutton(app.RightPanel, 'push');
            app.ShowMaxProjectionButtonRFP.ButtonPushedFcn = createCallbackFcn(app, @ShowMaxProjectionButtonRFPPushed, true);
            app.ShowMaxProjectionButtonRFP.Text = 'Show Max Projection';
            app.GuessPVsSpinnerLabel = uilabel(app.RightPanel);
            app.GuessPVsSpinnerLabel.Text = 'Guess # PVs';
            app.GuessPVsSpinner = uispinner(app.RightPanel);
            app.GuessPVsSpinner.Limits = [0 10];
            app.GuessPVsSpinner.ValueChangedFcn = createCallbackFcn(app, @GuessPVsSpinnerValueChanged, true);
            app.GuessPVsSpinner.Value = 1;
            app.FindCellsButton = uibutton(app.RightPanel, 'push');
            app.FindCellsButton.ButtonPushedFcn = createCallbackFcn(app, @FindCellsButtonPushed, true);
            app.FindCellsButton.Text = 'Find Cells';
            app.GuessPVRadiusSpinnerLabel = uilabel(app.RightPanel);
            app.GuessPVRadiusSpinnerLabel.Text = 'Guess PV Radius';
            app.GuessPVRadiusSpinner = uispinner(app.RightPanel);
            app.GuessPVRadiusSpinner.Limits = [5 Inf];
            app.GuessPVRadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @GuessPVRadiusSpinnerValueChanged, true);
            app.GuessPVRadiusSpinner.Value = 5;
            app.GOButton = uibutton(app.RightPanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.Spinner = uispinner(app.RightPanel);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.toSpinnerLabel = uilabel(app.RightPanel);
            app.toSpinnerLabel.Text = 'to';
            app.toSpinner = uispinner(app.RightPanel);
            app.toSpinner.Limits = [2 Inf];
            app.toSpinner.ValueChangedFcn = createCallbackFcn(app, @toSpinnerValueChanged, true);
            app.toSpinner.Value = 2;
            app.IDButton = uibutton(app.RightPanel, 'push');
            app.IDButton.ButtonPushedFcn = createCallbackFcn(app, @IDButtonPushed, true);
            app.IDButton.Text = 'ID';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = quick_2p_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end